classdef createExperiement < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FoundFilesSelectwhichfileswillbeinexperimentLabel  matlab.ui.control.Label
        FoundFilesSelectwhichfileswillbeinexperimentListBox  matlab.ui.control.ListBox
        SelectProjectButton           matlab.ui.control.Button
        ExperimentNameEditFieldLabel  matlab.ui.control.Label
        ExperimentNameEditField       matlab.ui.control.EditField
        CreateExperimentButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        allFilePaths; % contains all the file names and paths located, which can then be subsequently filtered
        allFileNames;
        allFileFolders;
        project
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectProjectButton
        function SelectProjectButtonPushed(app, event)
            [projname, projdir] = uigetfile('*.proj');
            
            loadedProject = load([dirDelimiterCheck(projdir) projname], '-mat');
            
            app.project = loadedProject.project;
            
            for i = 1:length(app.project.recordings)
                name = app.project.recordings{i}.name;
                folder = dirDelimiterCheck(app.project.recordings{i}.folder)
                displayname{i} = [num2str(i) '.' folder name]
            end
            
            app.FoundFilesSelectwhichfileswillbeinexperimentListBox.Items = displayname;
            
            drawnow;
            figure(app.UIFigure)
        end

        % Button pushed function: CreateExperimentButton
        function CreateExperimentButtonPushed(app, event)
            
            project = app.project;
            
            selectedItems = app.FoundFilesSelectwhichfileswillbeinexperimentListBox.Value;
                       
            %parse to find their indicies
            for i = 1:length(selectedItems)
                k = strfind(selectedItems{i}, '.'); k = k(1);
                idx(i) = str2num(selectedItems{i}(1:k-1));
            end
        
            %create the experiment in a field
            if isfield(project, 'experiments')
                experiment = struct;
                experiment.name = app.ExperimentNameEditField.Value;
                experiment.recordingIds = idx;
                project.experiments = setfield(project.experiments, app.ExperimentNameEditField.Value, experiment);
            else
                project = setfield(project, 'experiments', struct);
                experiment = struct;
                experiment.name = app.ExperimentNameEditField.Value;
                experiment.recordingIds = idx;
                project.experiments = setfield(project.experiments, app.ExperimentNameEditField.Value, experiment);
                
            end

            %create an experiment subdirectory in each recording directory
            projdir = dirDelimiterCheck(app.project.location);
            for i = 1:length(idx)
                expdir = [projdir 'recordings/' app.project.recordings{idx(i)}.name '/experiments/' ];
                if ~isdir([expdir '/' app.ExperimentNameEditField.Value '/'])
                    [expdir '/' app.ExperimentNameEditField.Value '/' ' is already a directory']
                    mkdir([expdir '/' app.ExperimentNameEditField.Value '/'])
                end
            end
            
            if ~isdir([dirDelimiterCheck(app.project.location) 'experiments/' app.ExperimentNameEditField.Value '/'])
                mkdir([dirDelimiterCheck(app.project.location) 'experiments/' app.ExperimentNameEditField.Value '/'])
            end
            if ~isdir([dirDelimiterCheck(app.project.location) 'experiments/' app.ExperimentNameEditField.Value '/scripts/'])
                mkdir([dirDelimiterCheck(app.project.location) 'experiments/' app.ExperimentNameEditField.Value '/scripts/'])
            end
            
            save([dirDelimiterCheck(app.project.location) app.project.name '.proj'], 'project', '-mat')
 
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FoundFilesSelectwhichfileswillbeinexperimentLabel = uilabel(app.UIFigure);
            app.FoundFilesSelectwhichfileswillbeinexperimentLabel.Text = {'Found Files'; ''; 'Select which files '; 'will be in experiment'};
            app.FoundFilesSelectwhichfileswillbeinexperimentListBox = uilistbox(app.UIFigure);
            app.FoundFilesSelectwhichfileswillbeinexperimentListBox.Items = {};
            app.FoundFilesSelectwhichfileswillbeinexperimentListBox.Multiselect = 'on';
            app.FoundFilesSelectwhichfileswillbeinexperimentListBox.Value = {};
            app.SelectProjectButton = uibutton(app.UIFigure, 'push');
            app.SelectProjectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectProjectButtonPushed, true);
            app.SelectProjectButton.Text = 'Select Project';
            app.ExperimentNameEditFieldLabel = uilabel(app.UIFigure);
            app.ExperimentNameEditFieldLabel.Text = 'Experiment Name';
            app.ExperimentNameEditField = uieditfield(app.UIFigure, 'text');
            app.CreateExperimentButton = uibutton(app.UIFigure, 'push');
            app.CreateExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @CreateExperimentButtonPushed, true);
            app.CreateExperimentButton.Text = 'Create Experiment';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = createExperiement

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end