classdef addLabels < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        LoadProjectButton           matlab.ui.control.Button
        RecordingsinProjectListBoxLabel  matlab.ui.control.Label
        RecordingsinProjectListBox  matlab.ui.control.ListBox
        LabelEditFieldLabel         matlab.ui.control.Label
        LabelEditField              matlab.ui.control.EditField
        ApplyLabeltoSelectedRecordingsButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        projectStucture % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadProjectButton
        function LoadProjectButtonPushed(app, event)
            
            [projectname projectpath] = uigetfile('*.proj');
            
            projectpath = dirDelimiterCheck(projectpath);
            
            app.projectStucture = load([projectpath projectname], '-mat')
            
            dataStruct = app.projectStucture.recordingData;
                        
            for i = 1:length(app.projectStucture.recordingData)
                recordingFolder = dataStruct{i}.folder; recordingFolder = dirDelimiterCheck(recordingFolder);
                displaynames{i} = [num2str(i) '.' recordingFolder dataStruct{i}.name];
            end
            
            app.RecordingsinProjectListBox.Items = displaynames;
            
            drawnow;
            figure(app.UIFigure)
            
        end

        % Value changed function: RecordingsinProjectListBox
        function RecordingsinProjectListBoxValueChanged(app, event)
            value = app.RecordingsinProjectListBox.Value;
            
        end

        % Button pushed function: 
        % ApplyLabeltoSelectedRecordingsButton
        function ApplyLabeltoSelectedRecordingsButtonPushed(app, event)
            
            %get the selected items
            selectedItems = app.RecordingsinProjectListBox.Value;
            
            %parse to find their indicies
            for i = 1:length(selectedItems)
                k = strfind(selectedItems{i}, '.'); k = k(1);
                idx(i) = str2num(selectedItems{i}(1:k-1));
                
            end
            
            idx = sort(idx)
            
            for i = 1:length(app.projectStucture)
                app.projectStucture.recordingData{idx(i)}.label = app.LabelEditField.Value;
            end
              
            assignin('base','recordingData', app.projectStucture.recordingData)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadProjectButton = uibutton(app.UIFigure, 'push');
            app.LoadProjectButton.ButtonPushedFcn = createCallbackFcn(app, @LoadProjectButtonPushed, true);
            app.LoadProjectButton.Text = 'Load Project';
            app.RecordingsinProjectListBoxLabel = uilabel(app.UIFigure);
            app.RecordingsinProjectListBoxLabel.Text = 'Recordings in Project';
            app.RecordingsinProjectListBox = uilistbox(app.UIFigure);
            app.RecordingsinProjectListBox.Items = {};
            app.RecordingsinProjectListBox.Multiselect = 'on';
            app.RecordingsinProjectListBox.ValueChangedFcn = createCallbackFcn(app, @RecordingsinProjectListBoxValueChanged, true);
            app.RecordingsinProjectListBox.Value = {};
            app.LabelEditFieldLabel = uilabel(app.UIFigure);
            app.LabelEditField = uieditfield(app.UIFigure, 'text');
            app.ApplyLabeltoSelectedRecordingsButton = uibutton(app.UIFigure, 'push');
            app.ApplyLabeltoSelectedRecordingsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyLabeltoSelectedRecordingsButtonPushed, true);
            app.ApplyLabeltoSelectedRecordingsButton.Text = 'Apply Label to Selected Recordings';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = addLabels

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end