classdef addRecordingsToExistingProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SearchDirectoriesButton  matlab.ui.control.Button
        FoundFilesListBoxLabel   matlab.ui.control.Label
        FoundFilesListBox        matlab.ui.control.ListBox
        AddToProjectButton       matlab.ui.control.Button
        SelectProjectButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        allFilePaths; % contains all the file names and paths located, which can then be subsequently filtered
        allFileNames;
        allFileFolders;
        project
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SearchDirectoriesButton
        function SearchDirectoriesButtonPushed(app, event)
            [rootfolder] = uigetdir();
            
            rootfolder = dirDelimiterCheck(rootfolder);
            S = dir(fullfile(rootfolder, ['**\*' '.raw']));
            names = {S.name};
            folders = {S.folder};
            
            for i = 1:length(names)
                filepaths{i} = [folders{i} names{i}];
            end
            
            app.allFilePaths = filepaths;
            app.allFileNames = names;
            app.allFileFolders = folders;
            
            app.FoundFilesListBox.Items = filepaths;
            drawnow;
            figure(app.UIFigure)
        end

        % Button pushed function: AddToProjectButton
        function AddToProjectButtonPushed(app, event)
            
        
            fileStructure = app.project.recordings;
            projectdir = app.project.location;
            
            selectedItems = app.FoundFilesListBox.Value;
            allItems = app.allFilePaths;
            
            fileStructure{1}
            
            for i = 1:length(selectedItems)
                idx(i) = find(strcmp(allItems, selectedItems{i}) == 1);
            end
            
            folders = {app.allFileFolders{idx}};
            names = {app.allFileNames{idx}};
            %parse to find extensions
            
            %parse to find their indicies
            for i = 1:length(selectedItems)
                k = strfind(names{i}, '.'); k = k(end);
                extensions{i} = names{i}(k:end);
                names{i} = names{i}(1:k-1);
                
            end
            
            count = 1;
            for i = length(fileStructure)+1:length(fileStructure)+length(names)
                mkdir([projectdir '/recordings/' names{count} '/'])
                mkdir([projectdir '/recordings/' names{count} '/experiments/'])
                mkdir([projectdir '/recordings/' names{count} '/originalData/'])
                
                
                fileStructure{i}.name = names{count};
                fileStructure{i}.extension = extensions{count}
                fileStructure{i}.folder = folders{count};
                
                fileStructure{i}.meta = struct;
                fileStructure{i}.meta.processed = false;
                count = count+1;
            end
            %
            %             for i = 1:length(uniqueFolders)
            %                 idx = find(strcmp(app.allFileFolders, uniqueFolders{i})==1);
            %                 for j = 1:length(idx)
            %                     files{j} = [names{idx(j)}];
            %                 end
            %
            %                 fileStructure{i}.folder = uniqueFolders{i};
            %                 fileStructure{i}.files = files;
            %
            %
            %             end
            
            %
            %assignin('base','foundGroupsStructure', fileStructure)

            app.project.recordings = fileStructure;
            
            project.name = app.project.name;
            project.location = app.project.location;
            project.recordings = app.project.recordings;
            
            save([dirDelimiterCheck(projectdir) app.project.name '.proj'], 'project', '-mat')
            
        end

        % Button pushed function: SelectProjectButton
        function SelectProjectButtonPushed(app, event)
            [projname, projdir] = uigetfile('*.proj');
            
            loadedProject = load([dirDelimiterCheck(projdir) projname], '-mat');
            
            app.project = loadedProject.project;
            
            drawnow;
            figure(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SearchDirectoriesButton = uibutton(app.UIFigure, 'push');
            app.SearchDirectoriesButton.ButtonPushedFcn = createCallbackFcn(app, @SearchDirectoriesButtonPushed, true);
            app.SearchDirectoriesButton.Text = 'Search Directories';
            app.FoundFilesListBoxLabel = uilabel(app.UIFigure);
            app.FoundFilesListBoxLabel.Text = 'Found Files';
            app.FoundFilesListBox = uilistbox(app.UIFigure);
            app.FoundFilesListBox.Items = {};
            app.FoundFilesListBox.Multiselect = 'on';
            app.FoundFilesListBox.Value = {};
            app.AddToProjectButton = uibutton(app.UIFigure, 'push');
            app.AddToProjectButton.ButtonPushedFcn = createCallbackFcn(app, @AddToProjectButtonPushed, true);
            app.AddToProjectButton.Text = 'Add To Project';
            app.SelectProjectButton = uibutton(app.UIFigure, 'push');
            app.SelectProjectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectProjectButtonPushed, true);
            app.SelectProjectButton.Text = 'Select Project';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = addRecordingsToExistingProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end