classdef startProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SearchDirectoriesButton    matlab.ui.control.Button
        FoundFilesListBoxLabel     matlab.ui.control.Label
        FoundFilesListBox          matlab.ui.control.ListBox
        CreateProjectButton        matlab.ui.control.Button
        SetprojectdirectoryButton  matlab.ui.control.Button
        ProjectnameEditFieldLabel  matlab.ui.control.Label
        ProjectnameEditField       matlab.ui.control.EditField
        DataTypeListBoxLabel       matlab.ui.control.Label
        DataTypeListBox            matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        allFilePaths; % contains all the file names and paths located, which can then be subsequently filtered
        allFileNames;
        allFileFolders;
        projectDirectory
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SearchDirectoriesButton
        function SearchDirectoriesButtonPushed(app, event)
            [rootfolder] = uigetdir();
            
            rootfolder = dirDelimiterCheck(rootfolder);
            S = dir(fullfile(rootfolder, ['**/*' app.DataTypeListBox.Value]));
            names = {S.name};
            folders = {S.folder};
            
            for i = 1:length(names)
                filepaths{i} = [folders{i} names{i}];
            end
            
            app.allFilePaths = filepaths;
            app.allFileNames = names;
            app.allFileFolders = folders;
            
            app.FoundFilesListBox.Items = filepaths;
            drawnow;
            figure(app.UIFigure)
        end

        % Button pushed function: CreateProjectButton
        function CreateProjectButtonPushed(app, event)
            
            %set up project directory
            projectdir = [dirDelimiterCheck(app.projectDirectory) app.ProjectnameEditField.Value '/'];
            mkdir(projectdir);
            mkdir([projectdir '/recordings/']);
            
            selectedItems = app.FoundFilesListBox.Value;
            allItems = app.allFilePaths;
            
            
            for i = 1:length(selectedItems)
                idx(i) = find(strcmp(allItems, selectedItems{i}) == 1);
            end
            
            folders = {app.allFileFolders{idx}};
            names = {app.allFileNames{idx}};
            %parse to find extensions
            
            %parse to find their indicies
            for i = 1:length(selectedItems)
                k = strfind(names{i}, '.'); k = k(end);
                extensions{i} = names{i}(k:end);
                names{i} = names{i}(1:k-1);
                
            end
            
            for i = 1:length(names)
                mkdir([projectdir '/recordings/'  names{i} '/'])
                mkdir([projectdir '/recordings/'  names{i} '/experiments/'])
                mkdir([projectdir '/recordings/'  names{i} '/originalData/'])
                
                fileStructure{i}.name = names{i};
                fileStructure{i}.extension = extensions{i}
                fileStructure{i}.folder = folders{i};
                
                
                fileStructure{i}.meta = struct;
                fileStructure{i}.meta.processed = false;
                
            end
            %
            %             for i = 1:length(uniqueFolders)
            %                 idx = find(strcmp(app.allFileFolders, uniqueFolders{i})==1);
            %                 for j = 1:length(idx)
            %                     files{j} = [names{idx(j)}];
            %                 end
            %
            %                 fileStructure{i}.folder = uniqueFolders{i};
            %                 fileStructure{i}.files = files;
            %
            %
            %             end
            
            %
            %assignin('base','foundGroupsStructure', fileStructure)
            
            project.name = app.ProjectnameEditField.Value;
            project.location = projectdir;
            project.recordings = fileStructure;
            
            save([projectdir app.ProjectnameEditField.Value '.proj'], 'project', '-mat')
            
        end

        % Button pushed function: SetprojectdirectoryButton
        function SetprojectdirectoryButtonPushed(app, event)
            app.projectDirectory = uigetdir();
            drawnow;
            figure(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SearchDirectoriesButton = uibutton(app.UIFigure, 'push');
            app.SearchDirectoriesButton.ButtonPushedFcn = createCallbackFcn(app, @SearchDirectoriesButtonPushed, true);
            app.SearchDirectoriesButton.Text = 'Search Directories';
            app.FoundFilesListBoxLabel = uilabel(app.UIFigure);
            app.FoundFilesListBoxLabel.Text = 'Found Files';
            app.FoundFilesListBox = uilistbox(app.UIFigure);
            app.FoundFilesListBox.Items = {};
            app.FoundFilesListBox.Multiselect = 'on';
            app.FoundFilesListBox.Value = {};
            app.CreateProjectButton = uibutton(app.UIFigure, 'push');
            app.CreateProjectButton.ButtonPushedFcn = createCallbackFcn(app, @CreateProjectButtonPushed, true);
            app.CreateProjectButton.Text = 'Create Project';
            app.SetprojectdirectoryButton = uibutton(app.UIFigure, 'push');
            app.SetprojectdirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SetprojectdirectoryButtonPushed, true);
            app.SetprojectdirectoryButton.Text = 'Set project directory';
            app.ProjectnameEditFieldLabel = uilabel(app.UIFigure);
            app.ProjectnameEditFieldLabel.Text = 'Project name';
            app.ProjectnameEditField = uieditfield(app.UIFigure, 'text');
            app.DataTypeListBoxLabel = uilabel(app.UIFigure);
            app.DataTypeListBoxLabel.Text = 'Data Type';
            app.DataTypeListBox = uilistbox(app.UIFigure);
            app.DataTypeListBox.Items = {'.raw', '.tif'};
            app.DataTypeListBox.Value = '.raw';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = startProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end